classdef RadHARSimulatorV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RadHARSimulatorV3RadHARAGTUIFigure  matlab.ui.Figure
        TextArea_2               matlab.ui.control.TextArea
        ResultsPlottingLabel     matlab.ui.control.Label
        AuthorJoeyBGAffiliationBeijingInstituteofTechnologyLabel  matlab.ui.control.Label
        Switch                   matlab.ui.control.RockerSwitch
        TemperatureSlider        matlab.ui.control.Slider
        TemperatureSliderLabel   matlab.ui.control.Label
        AgentWorkingLabel        matlab.ui.control.Label
        StatusLamp               matlab.ui.control.Lamp
        ImportLLMEditField       matlab.ui.control.EditField
        ImportLLMEditFieldLabel  matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        ChooseImageButton        matlab.ui.control.Button
        WhatcanIsimulateforyoutodayLabel  matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        Image                    matlab.ui.control.Image
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
       
        function agent_think(app, text_str)
            % Convert input to character array for stream processing
            char_array = char(text_str);
            
            % Retrieve the current text from the command window text area
            current_text = app.TextArea_2.Value;
            
            % Initialize safely preventing empty cell array errors
            if isempty(current_text) || (isscalar(current_text) && strlength(string(current_text{1})) == 0)
                current_text = {''};
            else
                current_text{end+1} = ''; % Start a new line for new agent thought
            end
            
            app.TextArea_2.Value = current_text;
            
            % Stream characters one by one simulating cognitive typing
            for idx = 1:length(char_array)
                if char_array(idx) == char(10) % Handle explicit newline characters gracefully
                    current_text{end+1} = '';
                elseif char_array(idx) ~= char(13) % Ignore CR
                    current_text{end} = [current_text{end}, char_array(idx)];
                end
                
                % Update UI dynamically to balance visual smoothness and system rendering performance
                if mod(idx, 3) == 0 || idx == length(char_array)
                    app.TextArea_2.Value = current_text;
                    scroll(app.TextArea_2, 'bottom');
                    drawnow limitrate;
                end
                pause(0.015); % Micro-pause maintaining UI fluid responsiveness
            end
            
            % Ensure final state is robustly updated
            app.TextArea_2.Value = current_text;
            scroll(app.TextArea_2, 'bottom');
            drawnow;
        end

        function M = compute_bone_matrix(app, p1, p2, radius)
            v = p2 - p1;
            L = norm(v);
            
            % Mask the invalid geometries nulling scaling magnitude explicitly
            if L < 1e-5
                M = makehgtform('scale', 0);
                return;
            end
            
            % Evaluate the orienting cosine products estimating target angles properly
            dir_vec = v / L;
            z_axis = [0, 0, 1];
            
            % Construct rotational instances differentiating co linear singular vectors cautiously
            if norm(cross(z_axis, dir_vec)) < 1e-5
                if dot(z_axis, dir_vec) > 0
                    R = eye(4); 
                else
                    R = makehgtform('xrotate', pi); 
                end
            else
                rot_axis = cross(z_axis, dir_vec);
                rot_axis = rot_axis / norm(rot_axis);
                rot_angle = acos(dot(z_axis, dir_vec));
                R = makehgtform('axisrotate', rot_axis, rot_angle);
            end
            
            % Stack the final concatenated sequential block defining spatial states accurately
            T = makehgtform('translate', p1);
            S = makehgtform('scale', [radius, radius, L]);
            
            M = T * R * S;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % Only execute when the rocker switch is actively turned 'On'
            if ~strcmp(app.Switch.Value, 'On')
                return;
            end
            
            % Update UI status lamp to yellow indicating active processing
            app.StatusLamp.Color = [1 1 0]; 
            app.TextArea_2.Value = {''};
            
            % Clear visual UI axes dynamically before generating new simulation
            delete(allchild(app.UIAxes));
            delete(allchild(app.UIAxes_2));
            delete(allchild(app.UIAxes_3));
            delete(allchild(app.UIAxes_4));
            delete(allchild(app.UIAxes_5));

            colorbar(app.UIAxes, 'off');
            colorbar(app.UIAxes_2, 'off');
            colorbar(app.UIAxes_3, 'off');
            colorbar(app.UIAxes_4, 'off');
            colorbar(app.UIAxes_5, 'off');
            legend(app.UIAxes_5, 'off');
            
            drawnow;
            
            % Save original directory preventing local path collapsing during execution faults
            original_dir = pwd;
            
            try
                % Replace default print with agent thinking stream mapping to UI
                app.agent_think("Author: JoeyBG. RadHARAGT system initialized.");
                
                %% Definition
                % Map parameters directly from App Designer UI components
                Model_Name = string(app.ImportLLMEditField.Value);                                                 
                
                % Parse input text formatting it strictly
                Input_Text_Cell = app.TextArea.Value;
                if iscell(Input_Text_Cell)
                    Input_Text = strjoin(string(Input_Text_Cell), ' ');
                else
                    Input_Text = string(Input_Text_Cell);
                end
                
                % Map image path and flag conditionally
                Image_Path = string(app.EditField.Value);
                if ~isempty(Image_Path) && strlength(Image_Path) > 0 && isfile(Image_Path)
                    Has_Image = true;
                else
                    Has_Image = false;
                end
                
                % Define the parameters for the motion generation execution
                EMDM_Path = "EMDM";                                                         
                Conda_Env = "emdm";                                                         
                Guidance_Param = num2str(app.TemperatureSlider.Value, '%.1f');                                                     
                Motion_FPS = 20;                                                            
                
                % Define the visualization style parameters conforming to explicit requirements
                Font_Name = 'Comic Sans MS';                                            
                Font_Size_Basis = 14;                                                       
                Font_Size_Axis = 14;                                                        
                Font_Size_Title = 14;                                                       
                Font_Weight_Basis = 'normal';                                               
                Font_Weight_Axis = 'normal';                                                
                Font_Weight_Title = 'bold';
                
                % Define the custom colormap colors for the visualization plots
                JoeyBG_Colormap = [0.6196 0.0039 0.2588;                                     
                                   0.8353 0.2431 0.3098;
                                   0.9569 0.4275 0.2627;
                                   0.9922 0.6824 0.3804;
                                   0.9961 0.8784 0.5451;
                                   1.0000 1.0000 0.7490;
                                   0.9020 0.9608 0.5961;
                                   0.6706 0.8667 0.6431;
                                   0.4000 0.7608 0.6471;
                                   0.1961 0.5333 0.7412;
                                   0.3686 0.3098 0.6353];
                JoeyBG_Colormap_Flip = flip(JoeyBG_Colormap);
                
                % Create a timestamped folder to save all simulation outputs
                current_time_str = char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
                Save_Dir = sprintf('Simulation_Result_%s', current_time_str);
                if ~exist(Save_Dir, 'dir')
                    mkdir(Save_Dir);
                end
                
                app.agent_think(sprintf("Allocating local workspace directory at %s to securely store all generated simulation outputs.", Save_Dir));
                
                % Define the system prompt template to instruct the model for dual task extraction
                JSON_Template = sprintf(['{\n', ...
                    '  "Refined_Prompt": "...",\n', ...
                    '  "fc": 77000000000,\n', ...
                    '  "tp": 0.001,\n', ...
                    '  "B": 4000000000,\n', ...
                    '  "PRF": 1000,\n', ...
                    '  "fs": 10000000,\n', ...
                    '  "tx_pos": [0, 2, 1],\n', ...
                    '  "rx_pos":[0, 2, 1],\n', ...
                    '  "antenna_gain": 15,\n', ...
                    '  "antenna_isolation": 40,\n', ...
                    '  "SNR": 50,\n', ...
                    '  "enable_wall": false,\n', ...
                    '  "enable_multipath": true,\n', ...
                    '  "wall_center":[0, 1, 1],\n', ...
                    '  "wall_dimensions":[3, 0.2, 2.5],\n', ...
                    '  "wall_epsilon_r": 4.5,\n', ...
                    '  "wall_loss_tangent": 0.05,\n', ...
                    '  "stft_window_seconds": 0.1,\n', ...
                    '  "stft_overlap_ratio": 0.75,\n', ...
                    '  "enable_network": true\n', ...
                    '}']);
                
                % Construct the full text instruction for the language model
                System_Prompt = strcat("你现在是一个专业的Human Motion生成与雷达仿真物理参数提取专家。\n"...
                    , "你的核心任务是理解用户对'室内人体动作与雷达探测场景'的自然语言描述，并将其精准地转化为结构化的JSON参数字典。\n\n"...
                    , "【任务一：动作语义转换 (Refined_Prompt)】\n"...
                    , "将用户的基础动作描述翻译并润色为适合HumanML3D/MDM生成3D序列的英文Prompt（10-30词）。对于跌倒、坐下等快速且有明确终态的动作，必须明确指出速度和最终姿态（例如：quickly falls down and remains lying on the ground）。\n\n"...
                    , "【任务二：雷达与物理场景参数提取】\n"...
                    , "从描述中提取雷达射频参数、天线位置及场景媒质属性。请严格遵循以下物理单位和坐标系定义（若用户未提供某些参数，请根据典型的室内FMCW/UWB人体雷达探测经验补充合理默认值）：\n"...
                    , "- 射频参数（必须转换为基本单位）：中心频率 fc (Hz，例如2GHz必须输出为 2000000000)、带宽 B (Hz，例如1GHz为 1000000000)、脉冲宽度 tp (秒, 默认 1e-3)、脉冲重复频率 PRF (Hz)、采样率 fs (Hz, 默认 10e6)。\n"...
                    , "- 系统与天线：系统信噪比 SNR (dB, 默认 50)、天线增益 antenna_gain (dBi, 默认 15)、收发隔离度 antenna_isolation (dB, 默认 40)。\n"...
                    , "- 坐标系定义（绝对准则）：所有的三维向量必须严格遵守 [X(方位/宽度), Y(距离/深度/厚度), Z(高度)] 的笛卡尔物理坐标系。\n"...
                    , "- 天线位置：发射天线 tx_pos 和接收天线 rx_pos 格式为 [X, Y, Z] (单位：米)。\n"...
                    , "- 墙体参数（重要）：若语义中包含墙体则 enable_wall = true，墙体中心 wall_center 为 [X, Y, Z]。**墙体尺寸 wall_dimensions 必须且只能按照 [X轴宽度, Y轴厚度, Z轴高度] 的顺序输出！** 例如用户说'宽5米、高3米、厚0.2米'，你必须输出为 [5.0, 0.2, 3.0]！墙体介电常数 wall_epsilon_r 和损耗角正切 wall_loss_tangent 按原意提取。\n"...
                    , "- 信号处理：stft_window_seconds (秒, 默认 0.1)，stft_overlap_ratio (0~1之间, 默认 0.75)。\n"...
                    , "- 功能开关 (布尔值 true/false)：是否启用墙体 (enable_wall)、是否考虑多径效应 (enable_multipath)、是否使用神经网络进行特征增强/去噪 (enable_network)。如果用户明确说不使用某项功能，设为 false。\n\n"...
                    , "【输出格式严格约束】\n"...
                    , "你的输出必须且只能是一个合法的JSON对象，完全匹配下方模板的键值名称和数据类型。绝对不要输出任何额外的思考过程、问候语或 ```json 等Markdown标记！\n"...
                    , "模板示例：\n", JSON_Template);
                
                %% Connect to Ollama and Generate Prompt & Parameters
                app.agent_think("Initiating connection to the local large language model to comprehend the scene description and extract required simulation parameters.");
                
                Combined_Text = strcat(System_Prompt, newline, newline, "【用户的初始描述】:", Input_Text);
                
                if Has_Image
                    if isfile(Image_Path)
                        app.agent_think(sprintf("Reading and encoding the specified reference visual material from %s into base64 format for multimodal analysis.", Image_Path));
                        
                        fid = fopen(Image_Path, 'rb');
                        imgData = fread(fid, '*uint8');
                        fclose(fid);
                        imgBase64 = matlab.net.base64encode(imgData);
                        
                        apiEndpoint = "http://localhost:11434/api/generate";
                        options = weboptions('MediaType', 'application/json', 'Timeout', 120);
                        
                        payload = struct(...
                            'model', Model_Name, ...
                            'prompt', Combined_Text, ...
                            'stream', false, ...
                            'images', {cellstr(imgBase64)} ... 
                        );
                        
                        app.agent_think("Transmitting the multimodal request payload to the local language model and awaiting cognitive response.");
                        
                        response = webwrite(apiEndpoint, payload, options);
                        LLM_Raw_Response = response.response;
                    else
                        error("Error. The specified reference image file does not exist.");
                    end
                else
                    app.agent_think("No visual reference material was detected. Proceeding exclusively with text based semantic reasoning.");
                    
                    chat = ollamaChat(Model_Name);
                    [responseMsg, ~] = generate(chat, Combined_Text);
                    LLM_Raw_Response = responseMsg; 
                end

                %% Parse JSON to Initialize Simulation_Params
                app.agent_think("Intercepted communication from the language model. Decoding the structured data payload to extract physical variables.");
                
                json_str = strtrim(string(LLM_Raw_Response));
                
                idx_first = strfind(json_str, '{');
                idx_last = strfind(json_str, '}');
                if ~isempty(idx_first) && ~isempty(idx_last)
                    json_str = extractBetween(json_str, idx_first(1), idx_last(end), 'Boundaries', 'inclusive');
                else
                    json_str = regexprep(json_str, '^```json\s*', '');
                    json_str = regexprep(json_str, '^```\s*', '');
                    json_str = regexprep(json_str, '\s*```$', '');
                end
                
                try
                    Simulation_Params = jsondecode(json_str);
                catch ME
                    app.agent_think("Encountered a critical structural failure while attempting to decode the format of the response. Printing the raw output text below for physical diagnostics.");
                    app.agent_think(json_str);
                    error("The language model did not return a valid structured format.");
                end
                
                Default_Params = struct('fc', 77e9, 'tp', 1e-3, 'B', 4e9, 'PRF', 4000, ...
                    'fs', 10e6, 'tx_pos', [0, 5, 1], 'rx_pos', [0, 5, 1], 'antenna_gain', 15, ...
                    'antenna_isolation', 40, 'SNR', 50, 'enable_wall', false, 'enable_multipath', true, ...
                    'wall_center', [0, 1, 1], 'wall_dimensions', [3, 0.2, 2.5], ...
                    'wall_epsilon_r', 4.5, 'wall_loss_tangent', 0.05, ...
                    'stft_window_seconds', 0.1, 'stft_overlap_ratio', 0.75, 'enable_network', false);
                
                fields_list = fieldnames(Default_Params);
                for idx_field = 1:length(fields_list)
                    if ~isfield(Simulation_Params, fields_list{idx_field})
                        Simulation_Params.(fields_list{idx_field}) = Default_Params.(fields_list{idx_field});
                    end
                end
                
                if isfield(Simulation_Params, 'Refined_Prompt')
                    Refined_Prompt = Simulation_Params.Refined_Prompt;
                else
                    error("The parsed data structure is missing the refined prompt field.");
                end

                app.agent_think("Successfully extracted radar hardware properties and refined the semantic text prompt for the downstream framework.");
                
                %% Call EMDM to Generate 3D Motion Data
                app.agent_think("Delegating the three dimensional human motion trajectory generation to the deep diffusion mathematical framework utilizing the newly optimized prompt.");
                
                Cleaned_Prompt = string(Refined_Prompt);
                Cleaned_Prompt = strrep(Cleaned_Prompt, newline, ' ');
                Cleaned_Prompt = strrep(Cleaned_Prompt, '"', '');
                Cleaned_Prompt = strrep(Cleaned_Prompt, '''', '');
                
                cd(EMDM_Path);
                
                pre_run_files = dir(fullfile('models', '**', 'results.npy'));
                pre_run_paths = string(fullfile({pre_run_files.folder}, {pre_run_files.name}));
                
                model_pth = fullfile('models', 'HumanML3D.pth');
                cmd = sprintf('conda run -n %s python sample_mdm.py --text_prompt "%s" --model_path %s --dataset humanml --guidance_param %s', ...
                              Conda_Env, Cleaned_Prompt, model_pth, Guidance_Param);
                              
                app.agent_think(sprintf("Executing system shell command to trigger the remote python inference kernel and synthesize the complex action progression. The command is %s", cmd));
                
                system(cmd);
                
                app.agent_think("The motion progression sequence has been successfully compiled and synthesized by the external python computation environment.");
                
                %% Find & Load Generated Results
                post_run_files = dir(fullfile('models', '**', 'results.npy'));
                post_run_paths = string(fullfile({post_run_files.folder}, {post_run_files.name}));
                newly_generated_files = setdiff(post_run_paths, pre_run_paths);
                
                if isempty(newly_generated_files)
                    cd(original_dir);
                    app.agent_think("Action generation failed. No valid resulting matrix was detected within the system pipeline.");
                    error("Action generation failed. No new resulting array was generated. Skipping visualization.");
                end
                
                latest_npy = char(newly_generated_files(end));
                
                temp_py = 'temp_npy2mat.py';
                temp_mat = 'temp_motion.mat';
                
                fid_py = fopen(temp_py, 'w');
                fprintf(fid_py, 'import sys\nimport numpy as np\nimport scipy.io as sio\n');
                fprintf(fid_py, 'npy_path = sys.argv[1]\nmat_path = sys.argv[2]\n');
                fprintf(fid_py, 'data = np.load(npy_path, allow_pickle=True)\n');
                fprintf(fid_py, 'if isinstance(data, np.ndarray) and data.shape == (): data = data.item()\n');
                fprintf(fid_py, 'motion = data.get("motion", data) if isinstance(data, dict) else data\n');
                fprintf(fid_py, 'motion = np.squeeze(motion)\n');
                fprintf(fid_py, 'while len(motion.shape) > 3: motion = motion[0]\n');
                fprintf(fid_py, 'if motion.shape[0] == 22 and motion.shape[1] == 3: motion = np.transpose(motion, (2, 0, 1))\n');
                fprintf(fid_py, 'sio.savemat(mat_path, {"motion": motion})\n');
                fclose(fid_py);
                
                conv_cmd = sprintf('conda run -n %s python %s "%s" "%s"', Conda_Env, temp_py, latest_npy, temp_mat);
                system(conv_cmd);
                
                if ~isfile(temp_mat)
                    if isfile(temp_py), delete(temp_py); end
                    cd(original_dir);
                    app.agent_think("Data format conversion pipeline collapsed. Python process failed to bridge external structures.");
                    error("Python conversion failed to create the target temporary data file. Please investigate python and deep learning environments.");
                end
                
                loaded_data = load(temp_mat);
                motion_mat = double(loaded_data.motion); 
                
                delete(temp_py);
                delete(temp_mat);
                cd(original_dir); 
                
                %% Post-Processing to Improve Motion Quality
                app.agent_think("Applying high order kinematic smoothing filters and calculating variance based temporal truncation limits to stabilize the raw structural geometry.");
                
                window_size = 9; 
                poly_order = 3;  
                if size(motion_mat, 1) > window_size
                    for j = 1:size(motion_mat, 2)
                        for d = 1:size(motion_mat, 3)
                            motion_mat(:, j, d) = sgolayfilt(motion_mat(:, j, d), poly_order, window_size);
                        end
                    end
                end
                
                orig_frames = size(motion_mat, 1);
                root_pos = motion_mat(:, 1, :);
                rel_motion = motion_mat - root_pos;
                rel_vel = diff(rel_motion, 1, 1); 
                
                frame_activity = sum(sum(rel_vel.^2, 2), 3); 
                idle_threshold = 1e-4; 
                is_active = frame_activity > idle_threshold;
                
                last_active_frame = orig_frames;
                for f = orig_frames-1 : -1 : 1
                    if is_active(f)
                        last_active_frame = f + 1;
                        break;
                    end
                end
                
                pad_frames = 5;
                cut_frame = min(last_active_frame + pad_frames, orig_frames);
                if cut_frame < orig_frames - 10 
                    motion_mat = motion_mat(1:cut_frame, :, :);
                end
                
                %% Build and Save the Comprehensive Simulation_Params Struct
                app.agent_think("Consolidating the extracted hardware properties and spatial vector coordinates into the persistent physical parameter collection system.");
                
                Simulation_Params.motion_mat = motion_mat;                     
                Simulation_Params.num_frames = size(motion_mat, 1);            
                Simulation_Params.num_joints = size(motion_mat, 2);            
                
                Simulation_Params.FPS = Motion_FPS;                            
                Simulation_Params.time_axis = (0 : Simulation_Params.num_frames - 1) / Motion_FPS; 
                
                kinematic_tree =[
                    1, 2; 1, 3; 1, 4; 2, 5; 3, 6; 4, 7; 5, 8; 6, 9; 
                    7, 10; 8, 11; 9, 12; 10, 13; 10, 14; 10, 15; 13, 16; 
                    14, 17; 15, 18; 17, 19; 18, 20; 19, 21; 20, 22
                ];
                Simulation_Params.kinematic_tree = kinematic_tree;
                
                Normalized_RCS =[1.0, 0.7, 0.7, 0.9, 0.5, 0.5, 0.8, 0.3, 0.3, 0.8, ...
                                  0.1, 0.1, 0.6, 0.5, 0.5, 0.85, 0.4, 0.4, 0.3, 0.3, ...
                                  0.1, 0.1];
                Simulation_Params.Normalized_RCS = Normalized_RCS;
                
                min_joint_radius = 0.02;
                max_joint_radius = 0.075;
                joint_radii = min_joint_radius + (max_joint_radius - min_joint_radius) * Normalized_RCS;
                
                Simulation_Params.joint_radii = joint_radii;                   
                Simulation_Params.bone_radius = 0.035;                         
                
                save(fullfile(Save_Dir, 'Simulation_Params.mat'), 'Simulation_Params');

                %% FMCW Radar Point-Scatterer Simulation
                app.agent_think("Engaging the frequency modulated continuous wave radar physics engine to computationally simulate all internal point scatterer interference echoes and wall reflections.");
                
                c = 3e8;                                            
                fc = Simulation_Params.fc;
                lambda = c / fc;                                    
                tp = Simulation_Params.tp;
                B = Simulation_Params.B;
                K = B / tp;                                         
                PRF = Simulation_Params.PRF;
                fs = Simulation_Params.fs;
                
                if isfield(Simulation_Params, 'antenna_gain')
                    G_lin = 10^(Simulation_Params.antenna_gain / 10);
                else
                    G_lin = 10^(10 / 10);                           
                end
                
                base_amp_factor = (lambda * G_lin) / ((4 * pi)^1.5);
                
                epsilon_0 = 8.854e-12;
                mu_0 = 4 * pi * 1e-7;
                omega = 2 * pi * fc;

                wall_alpha = 0.0;
                v_wall = c;
                wall_gamma = 0.0;
                wall_y_center = 0.0;
                wall_y_thickness = 0.0;
                wall_y_min = 0.0;
                wall_y_max = 0.0;
                
                if Simulation_Params.enable_wall
                    eps_r = Simulation_Params.wall_epsilon_r;
                    loss_tan = Simulation_Params.wall_loss_tangent;
                    
                    eps_real = eps_r * epsilon_0;
                    eps_imag = eps_real * loss_tan;
                    
                    wall_alpha = omega * sqrt(mu_0 * eps_real / 2 * (sqrt(1 + (eps_imag/eps_real)^2) - 1));
                    
                    v_wall = c / sqrt(eps_r);
                    wall_gamma = (1 - sqrt(eps_r)) / (1 + sqrt(eps_r));
                    
                    wall_y_center = Simulation_Params.wall_center(2);
                    wall_y_thickness = Simulation_Params.wall_dimensions(2);
                    wall_y_min = wall_y_center - wall_y_thickness / 2;
                    wall_y_max = wall_y_center + wall_y_thickness / 2;
                end
                
                tx_pos = Simulation_Params.tx_pos(:)';              
                rx_pos = Simulation_Params.rx_pos(:)';
                
                N_s = round(tp * fs);                               
                t_fast = (0:N_s-1)' / fs;
                
                t_motion = Simulation_Params.time_axis;             
                t_slow = 0 : 1/PRF : t_motion(end);                 
                num_pulses = length(t_slow);
                
                motion_radar = zeros(num_pulses, 22, 3);
                for j = 1:22
                    motion_radar(:,j,1) = interp1(t_motion, motion_mat(:,j,1), t_slow, 'pchip', 'extrap'); 
                    motion_radar(:,j,2) = interp1(t_motion, motion_mat(:,j,2), t_slow, 'pchip', 'extrap'); 
                    motion_radar(:,j,3) = interp1(t_motion, motion_mat(:,j,3), t_slow, 'pchip', 'extrap'); 
                end
                
                raw_echo = complex(zeros(N_s, num_pulses));
                
                rng(42); 
                joint_random_phases = 2 * pi * rand(22, 1);
                
                room_center = [0, 0, 1];
                tx_boresight = room_center - tx_pos;
                if norm(tx_boresight) > 1e-3, tx_boresight = tx_boresight / norm(tx_boresight); else, tx_boresight = [0, -1, 0]; end
                rx_boresight = room_center - rx_pos;
                if norm(rx_boresight) > 1e-3, rx_boresight = rx_boresight / norm(rx_boresight); else, rx_boresight = [0, -1, 0]; end

                app.agent_think("Calculating bistatic radar equations and multipath physics across all pulses. This might take some computational effort.");
                for p = 1:num_pulses
                    
                    signal_p = complex(zeros(N_s, 1));
                    
                    R_leakage = max(norm(tx_pos - rx_pos), 1e-3);
                    tau_leakage = R_leakage / c;
                    if isfield(Simulation_Params, 'antenna_isolation')
                        iso_lin = 10^(-Simulation_Params.antenna_isolation / 20);
                    else
                        iso_lin = 10^(-40 / 20);
                    end
                    amp_leakage = (lambda * G_lin) / (4 * pi * R_leakage) * iso_lin;
                    phase_leakage = 2*pi*(fc*tau_leakage + K*tau_leakage*t_fast - 0.5*K*tau_leakage^2);
                    signal_p = signal_p + amp_leakage .* exp(1i * phase_leakage);

                    for j = 1:22
                        target_pos =[motion_radar(p,j,1), motion_radar(p,j,3), motion_radar(p,j,2)];
                        
                        R_tx = norm(target_pos - tx_pos);
                        R_rx = norm(target_pos - rx_pos);
                        R_total = R_tx + R_rx;
                        
                        v_tx = (target_pos - tx_pos) / (R_tx + 1e-6);
                        v_rx = (target_pos - rx_pos) / (R_rx + 1e-6);
                        pat_tx = max(dot(v_tx, tx_boresight), 0)^2;
                        pat_rx = max(dot(v_rx, rx_boresight), 0)^2;
                        pattern_factor = pat_tx * pat_rx;
                        
                        d_tx_wall = 0; tau_tx_wall_delay = 0; trans_loss_tx = 1.0;
                        d_rx_wall = 0; tau_rx_wall_delay = 0; trans_loss_rx = 1.0;
                        wall_loss_factor = 1.0;
                        
                        if Simulation_Params.enable_wall
                            if (tx_pos(2) > wall_y_max && target_pos(2) < wall_y_min) || (tx_pos(2) < wall_y_min && target_pos(2) > wall_y_max)
                                cos_theta_tx = abs(target_pos(2) - tx_pos(2)) / (R_tx + 1e-6);
                                d_tx_wall = wall_y_thickness / max(cos_theta_tx, 0.01);
                                tau_tx_wall_delay = d_tx_wall / v_wall - d_tx_wall / c;
                                trans_loss_tx = max(1 - wall_gamma^2, 0); 
                            end
                            
                            if (rx_pos(2) > wall_y_max && target_pos(2) < wall_y_min) || (rx_pos(2) < wall_y_min && target_pos(2) > wall_y_max)
                                cos_theta_rx = abs(target_pos(2) - rx_pos(2)) / (R_rx + 1e-6);
                                d_rx_wall = wall_y_thickness / max(cos_theta_rx, 0.01);
                                tau_rx_wall_delay = d_rx_wall / v_wall - d_rx_wall / c;
                                trans_loss_rx = max(1 - wall_gamma^2, 0);
                            end
                            
                            wall_loss_factor = trans_loss_tx * trans_loss_rx * exp(-wall_alpha * (d_tx_wall + d_rx_wall));
                        end
                        
                        tau = R_total / c + tau_tx_wall_delay + tau_rx_wall_delay;
                        
                        amp = base_amp_factor * sqrt(Simulation_Params.Normalized_RCS(j)) / (R_tx * R_rx + 1e-6) * wall_loss_factor * pattern_factor;
                        
                        phase = 2*pi*(fc*tau + K*tau*t_fast - 0.5*K*tau^2) + joint_random_phases(j);
                        signal_p = signal_p + amp .* exp(1i * phase);
                        
                        if Simulation_Params.enable_multipath
                            gamma_gnd = -0.3; 
                            
                            target_pos_mp = [target_pos(1), target_pos(2), -target_pos(3)];
                            R_tx_mp = norm(target_pos_mp - tx_pos);
                            R_rx_mp = norm(target_pos_mp - rx_pos);
                            
                            d_tx_wall_mp = 0; tau_tx_mp_wall_delay = 0; trans_loss_tx_mp = 1.0;
                            d_rx_wall_mp = 0; tau_rx_mp_wall_delay = 0; trans_loss_rx_mp = 1.0;
                            
                            if Simulation_Params.enable_wall
                                if (tx_pos(2) > wall_y_max && target_pos_mp(2) < wall_y_min) || (tx_pos(2) < wall_y_min && target_pos_mp(2) > wall_y_max)
                                    cos_theta_tx_mp = abs(target_pos_mp(2) - tx_pos(2)) / (R_tx_mp + 1e-6);
                                    d_tx_wall_mp = wall_y_thickness / max(cos_theta_tx_mp, 0.01);
                                    tau_tx_mp_wall_delay = d_tx_wall_mp / v_wall - d_tx_wall_mp / c;
                                    trans_loss_tx_mp = max(1 - wall_gamma^2, 0);
                                end
                                if (rx_pos(2) > wall_y_max && target_pos_mp(2) < wall_y_min) || (rx_pos(2) < wall_y_min && target_pos_mp(2) > wall_y_max)
                                    cos_theta_rx_mp = abs(target_pos_mp(2) - rx_pos(2)) / (R_rx_mp + 1e-6);
                                    d_rx_wall_mp = wall_y_thickness / max(cos_theta_rx_mp, 0.01);
                                    tau_rx_mp_wall_delay = d_rx_wall_mp / v_wall - d_rx_wall_mp / c;
                                    trans_loss_rx_mp = max(1 - wall_gamma^2, 0);
                                end
                            end
                            
                            v_tx_mpA = (target_pos_mp - tx_pos) / (R_tx_mp + 1e-6);
                            pat_tx_mpA = max(dot(v_tx_mpA, tx_boresight), 0)^2;
                            pattern_factor_A = pat_tx_mpA * pat_rx;
                            
                            tau_mpA = (R_tx_mp + R_rx) / c + tau_tx_mp_wall_delay + tau_rx_wall_delay;
                            amp_loss_A = trans_loss_tx_mp * trans_loss_rx * exp(-wall_alpha * (d_tx_wall_mp + d_rx_wall));
                            amp_mpA = gamma_gnd * base_amp_factor * sqrt(Simulation_Params.Normalized_RCS(j)) / (R_tx_mp * R_rx + 1e-6) * amp_loss_A * pattern_factor_A; 
                            phase_mpA = 2*pi*(fc*tau_mpA + K*tau_mpA*t_fast - 0.5*K*tau_mpA^2) + joint_random_phases(j);
                            signal_p = signal_p + amp_mpA .* exp(1i * phase_mpA);
                            
                            v_rx_mpB = (target_pos_mp - rx_pos) / (R_rx_mp + 1e-6);
                            pat_rx_mpB = max(dot(v_rx_mpB, rx_boresight), 0)^2;
                            pattern_factor_B = pat_tx * pat_rx_mpB;
                            
                            tau_mpB = (R_tx + R_rx_mp) / c + tau_tx_wall_delay + tau_rx_mp_wall_delay;
                            amp_loss_B = trans_loss_tx * trans_loss_rx_mp * exp(-wall_alpha * (d_tx_wall + d_rx_wall_mp));
                            amp_mpB = gamma_gnd * base_amp_factor * sqrt(Simulation_Params.Normalized_RCS(j)) / (R_tx * R_rx_mp + 1e-6) * amp_loss_B * pattern_factor_B; 
                            phase_mpB = 2*pi*(fc*tau_mpB + K*tau_mpB*t_fast - 0.5*K*tau_mpB^2) + joint_random_phases(j);
                            signal_p = signal_p + amp_mpB .* exp(1i * phase_mpB);
                            
                            pattern_factor_C = pat_tx_mpA * pat_rx_mpB;
                            
                            tau_mpC = (R_tx_mp + R_rx_mp) / c + tau_tx_mp_wall_delay + tau_rx_mp_wall_delay;
                            amp_loss_C = trans_loss_tx_mp * trans_loss_rx_mp * exp(-wall_alpha * (d_tx_wall_mp + d_rx_wall_mp));
                            amp_mpC = (gamma_gnd^2) * base_amp_factor * sqrt(Simulation_Params.Normalized_RCS(j)) / (R_tx_mp * R_rx_mp + 1e-6) * amp_loss_C * pattern_factor_C; 
                            phase_mpC = 2*pi*(fc*tau_mpC + K*tau_mpC*t_fast - 0.5*K*tau_mpC^2) + joint_random_phases(j);
                            signal_p = signal_p + amp_mpC .* exp(1i * phase_mpC);
                            
                            if Simulation_Params.enable_wall
                                if target_pos(2) > wall_y_max
                                    target_wall_face = wall_y_max;
                                else
                                    target_wall_face = wall_y_min;
                                end
                                
                                target_pos_wall_mp = target_pos;
                                target_pos_wall_mp(2) = 2 * target_wall_face - target_pos(2);
                                R_tx_wall_mp = norm(target_pos_wall_mp - tx_pos);
                                R_rx_wall_mp = norm(target_pos_wall_mp - rx_pos);
                                
                                if (tx_pos(2) - target_wall_face) * (target_pos(2) - target_wall_face) > 0
                                    v_tx_wall_mp = (target_pos_wall_mp - tx_pos) / (R_tx_wall_mp + 1e-6);
                                    pat_tx_wall_mp = max(dot(v_tx_wall_mp, tx_boresight), 0)^2;
                                    pattern_factor_2A = pat_tx_wall_mp * pat_rx;
                                    
                                    tau_mp2A = (R_tx_wall_mp + R_rx) / c + tau_rx_wall_delay; 
                                    amp_mp2A = wall_gamma * base_amp_factor * sqrt(Simulation_Params.Normalized_RCS(j)) / (R_tx_wall_mp * R_rx + 1e-6) * trans_loss_rx * exp(-wall_alpha * d_rx_wall) * pattern_factor_2A;
                                    phase_mp2A = 2*pi*(fc*tau_mp2A + K*tau_mp2A*t_fast - 0.5*K*tau_mp2A^2) + joint_random_phases(j);
                                    signal_p = signal_p + amp_mp2A .* exp(1i * phase_mp2A);
                                end
                                
                                if (rx_pos(2) - target_wall_face) * (target_pos(2) - target_wall_face) > 0
                                    v_rx_wall_mp = (target_pos_wall_mp - rx_pos) / (R_rx_wall_mp + 1e-6);
                                    pat_rx_wall_mp = max(dot(v_rx_wall_mp, rx_boresight), 0)^2;
                                    pattern_factor_2B = pat_tx * pat_rx_wall_mp;
                                    
                                    tau_mp2B = (R_tx + R_rx_wall_mp) / c + tau_tx_wall_delay;
                                    amp_mp2B = wall_gamma * base_amp_factor * sqrt(Simulation_Params.Normalized_RCS(j)) / (R_tx * R_rx_wall_mp + 1e-6) * trans_loss_tx * exp(-wall_alpha * d_tx_wall) * pattern_factor_2B;
                                    phase_mp2B = 2*pi*(fc*tau_mp2B + K*tau_mp2B*t_fast - 0.5*K*tau_mp2B^2) + joint_random_phases(j);
                                    signal_p = signal_p + amp_mp2B .* exp(1i * phase_mp2B);
                                end
                                
                                if (d_tx_wall > 0 || d_rx_wall > 0)
                                    max_ringing_order = 5; 
                                    effective_gamma_rt = min(abs(wall_gamma) * 2.0, 0.85); 
                                    
                                    for m_ring = 1:max_ringing_order
                                        d_internal_bounce = m_ring * 2 * wall_y_thickness;
                                        tau_mp3 = tau + d_internal_bounce / v_wall;
                                        
                                        amp_mp3 = amp * (effective_gamma_rt^m_ring) * exp(-wall_alpha * d_internal_bounce); 
                                        
                                        phase_mp3 = 2*pi*(fc*tau_mp3 + K*tau_mp3*t_fast - 0.5*K*tau_mp3^2) + joint_random_phases(j);
                                        signal_p = signal_p + amp_mp3 .* exp(1i * phase_mp3);
                                    end
                                end
                                
                                if target_pos(2) < wall_y_min && tx_pos(2) > wall_y_max
                                    inner_wall_face = wall_y_min;
                                    is_valid_twt = true;
                                elseif target_pos(2) > wall_y_max && tx_pos(2) < wall_y_min
                                    inner_wall_face = wall_y_max;
                                    is_valid_twt = true;
                                else
                                    is_valid_twt = false; 
                                end
                                
                                if is_valid_twt
                                    D_tw = abs(target_pos(2) - inner_wall_face);
                                    if D_tw > 0.1
                                        tau_mp4 = tau + 2 * D_tw / c;
                                        amp_mp4 = amp * abs(wall_gamma) * (R_total / (R_total + 2 * D_tw)) * 0.5;
                                        phase_mp4 = 2*pi*(fc*tau_mp4 + K*tau_mp4*t_fast - 0.5*K*tau_mp4^2) + joint_random_phases(j);
                                        signal_p = signal_p + amp_mp4 .* exp(1i * phase_mp4);
                                        
                                        tau_mp5 = tau + 4 * D_tw / c;
                                        amp_mp5 = amp * (abs(wall_gamma)^2) * (R_total / (R_total + 4 * D_tw)) * 0.25;
                                        phase_mp5 = 2*pi*(fc*tau_mp5 + K*tau_mp5*t_fast - 0.5*K*tau_mp5^2) + joint_random_phases(j);
                                        signal_p = signal_p + amp_mp5 .* exp(1i * phase_mp5);
                                    end
                                end
                            end
                        end
                    end
                    
                    % Assign the synthesized pulse to the entire raw echo matrix
                    raw_echo(:, p) = signal_p;
                    
                    % Prevent UI thread freezing during intense radar synthesis
                    if mod(p, 50) == 0
                        drawnow limitrate;
                    end
                end
                
                ref_distance = 2.0;
                ref_amp = (lambda * G_lin * 1.0) / ((4*pi)^1.5 * ref_distance^2);
                ref_power = ref_amp^2;
                noise_power = (ref_power / (10^(Simulation_Params.SNR / 10))) * N_s;
                noise_matrix = sqrt(noise_power/2) * (randn(size(raw_echo)) + 1i * randn(size(raw_echo)));
                
                raw_echo = raw_echo + noise_matrix;
                save(fullfile(Save_Dir, 'Raw_Echo.mat'), 'raw_echo', '-v7.3');
                
                %% Signal Processing: RTM & DTM Generation
                app.agent_think("Executing discrete numerical fourier transforms upon the simulated echoes to map electromagnetic propagation properties into spatial range and velocity doppler coordinates.");
                
                fast_time_window = hamming(N_s);
                raw_echo_windowed = raw_echo .* fast_time_window;
                
                range_fft = fft(raw_echo_windowed, N_s, 1);
                RTM_complex = range_fft(1:floor(N_s/2), :);         
                
                f_beat = (0:floor(N_s/2)-1)' * (fs / N_s);
                range_axis = f_beat * c / (2 * K);

                max_indoor_range = 25.0; 
                
                valid_range_idx = range_axis <= max_indoor_range;
                RTM_complex = RTM_complex(valid_range_idx, :);
                range_axis = range_axis(valid_range_idx);
                
                clear range_fft raw_echo_windowed raw_echo noise_matrix;
                
                RTM_complex = RTM_complex - mean(RTM_complex, 2);
                
                STC_Weights = (range_axis.^2) + 1e-3;
                [~, ref_idx] = min(abs(range_axis - 2.0));
                STC_Weights = STC_Weights / STC_Weights(max(1, ref_idx)); 
                RTM_complex = RTM_complex .* STC_Weights;

                RTM_Mag_Lin = abs(RTM_complex);
                
                range_variance = var(RTM_Mag_Lin, 0, 2);
                active_threshold = max(range_variance) * 0.05; 
                active_bins = find(range_variance > active_threshold);
                
                if isempty(active_bins)
                    [~, max_idx] = max(mean(RTM_Mag_Lin, 2));
                    active_bins = max_idx;
                end
                
                plot_r_min = max(0, range_axis(active_bins(1)) - 2.0);
                plot_r_max = min(max_indoor_range, range_axis(active_bins(end)) + 8.0);
                
                n_window = max(round(Simulation_Params.stft_window_seconds * PRF), 8);
                n_overlap = min(round(n_window * Simulation_Params.stft_overlap_ratio), n_window - 1);
                [~, F_doppler, time_stft] = spectrogram(RTM_complex(active_bins(1), :), hamming(n_window), n_overlap, 1024, PRF, 'centered');
                
                DTM_Energy = zeros(length(F_doppler), length(time_stft));
                for b = 1:length(active_bins)
                    bin_idx = active_bins(b);
                    [S_bin, ~, ~] = spectrogram(RTM_complex(bin_idx, :), hamming(n_window), n_overlap, 1024, PRF, 'centered');
                    DTM_Energy = DTM_Energy + abs(S_bin).^2;
                end
                
                DTM_Energy = flipud(DTM_Energy);
                DTM_Mag_Lin = sqrt(DTM_Energy);

                %% Magnitude Normalization and Logarithmic Conversion
                app.agent_think("Normalizing matrix magnitudes and converting to logarithmic decibel scales to properly visualize the dynamic range of the radar signatures.");
                
                RTM_Mag_Log = 20*log10(RTM_Mag_Lin + 1e-12);
                DTM_Mag_Log = 20*log10(DTM_Mag_Lin + 1e-12);
                
                RTM_Mag_Log_Norm = RTM_Mag_Log - max(RTM_Mag_Log(:));
                DTM_Mag_Log_Norm = DTM_Mag_Log - max(DTM_Mag_Log(:));
                
                RTM_Mag_Lin_Norm = (RTM_Mag_Lin - min(RTM_Mag_Lin(:))) / (max(RTM_Mag_Lin(:)) - min(RTM_Mag_Lin(:)) + 1e-12);
                DTM_Mag_Lin_Norm = (DTM_Mag_Lin - min(DTM_Mag_Lin(:))) / (max(DTM_Mag_Lin(:)) - min(DTM_Mag_Lin(:)) + 1e-12);

                %% Network Denoising
                if Simulation_Params.enable_network
                    app.agent_think("Connecting the local deep learning denoising framework to systematically eliminate background static while preserving dynamic kinematic traces.");
                    try
                        net = denoisingNetwork('dncnn');
                        
                        denoise_matrix = @(Mat) ...
                            double(denoiseImage(single((Mat - min(Mat(:))) / (max(Mat(:)) - min(Mat(:)) + 1e-12)), net)) * (max(Mat(:)) - min(Mat(:))) + min(Mat(:));
                        
                        RTM_Mag_Log_Norm = denoise_matrix(RTM_Mag_Log_Norm);
                        DTM_Mag_Log_Norm = denoise_matrix(DTM_Mag_Log_Norm);
                        
                        RTM_Mag_Lin_Norm = denoise_matrix(RTM_Mag_Lin_Norm);
                        DTM_Mag_Lin_Norm = denoise_matrix(DTM_Mag_Lin_Norm);
                        
                    catch ME
                        app.agent_think("The deep neural network enhancement mechanism produced an internal operational failure. Terminating the refinement routine to maintain spectral stability.");
                    end
                end

                %% Export High Resolution Dataset Images
                app.agent_think("Formulating compressed high fidelity image matrices and writing them to the allocated drive space for downstream neural network integration.");
                
                log_clim_min = -30;
                log_clim_max = 0;
                
                RTM_Mag_Log_Clamped = max(min(RTM_Mag_Log_Norm, log_clim_max), log_clim_min);
                DTM_Mag_Log_Clamped = max(min(DTM_Mag_Log_Norm, log_clim_max), log_clim_min);
                
                custom_cmap_256 = interp1(linspace(0, 1, size(JoeyBG_Colormap_Flip, 1)), JoeyBG_Colormap_Flip, linspace(0, 1, 256));
                
                rtm_norm_img = (RTM_Mag_Log_Clamped - log_clim_min) ./ (log_clim_max - log_clim_min);
                rtm_rgb = ind2rgb(gray2ind(rtm_norm_img, 256), custom_cmap_256);
                rtm_resized = imresize(rtm_rgb, [1024, 1024]);
                imwrite(rtm_resized, fullfile(Save_Dir, 'RTM_Log_1024x1024_Image.jpg'));
                
                dtm_norm_img = (DTM_Mag_Log_Clamped - log_clim_min) ./ (log_clim_max - log_clim_min);
                dtm_rgb = ind2rgb(gray2ind(dtm_norm_img, 256), custom_cmap_256);
                dtm_resized = imresize(dtm_rgb,[1024, 1024]);
                imwrite(dtm_resized, fullfile(Save_Dir, 'DTM_Log_1024x1024_Image.jpg'));

                %% Plotting RTM and DTM User Interfaces
                app.agent_think("Generating independent graphical interface components displaying the structural distributions of the simulated matrices for visual inspection.");
                
                % Target RTM (Linear) visualization to app.UIAxes
                imagesc(app.UIAxes, t_slow, range_axis, RTM_Mag_Lin_Norm);
                set(app.UIAxes, 'YDir', 'normal', 'FontName', Font_Name, 'FontSize', Font_Size_Basis, 'FontWeight', Font_Weight_Basis);
                colormap(app.UIAxes, JoeyBG_Colormap_Flip); 
                clim(app.UIAxes, [0, 1]);
                title(app.UIAxes, 'RTM', 'FontName', Font_Name, 'FontSize', Font_Size_Title, 'FontWeight', Font_Weight_Title);
                xlabel(app.UIAxes, 'Time (s)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylabel(app.UIAxes, 'Range (m)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylim(app.UIAxes, [plot_r_min, plot_r_max]); 
                
                % Target DTM (Linear) visualization to app.UIAxes_2
                imagesc(app.UIAxes_2, time_stft, F_doppler, DTM_Mag_Lin_Norm);
                set(app.UIAxes_2, 'YDir', 'normal', 'FontName', Font_Name, 'FontSize', Font_Size_Basis, 'FontWeight', Font_Weight_Basis);
                colormap(app.UIAxes_2, JoeyBG_Colormap_Flip); 
                clim(app.UIAxes_2, [0, 1]);
                title(app.UIAxes_2, 'DTM', 'FontName', Font_Name, 'FontSize', Font_Size_Title, 'FontWeight', Font_Weight_Title);
                xlabel(app.UIAxes_2, 'Time (s)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylabel(app.UIAxes_2, 'Doppler (Hz)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylim(app.UIAxes_2, [-PRF/2, PRF/2]); 
                
                % Target RTM (Log) visualization to app.UIAxes_3
                imagesc(app.UIAxes_3, t_slow, range_axis, RTM_Mag_Log_Norm);
                set(app.UIAxes_3, 'YDir', 'normal', 'FontName', Font_Name, 'FontSize', Font_Size_Basis, 'FontWeight', Font_Weight_Basis);
                colormap(app.UIAxes_3, JoeyBG_Colormap_Flip); 
                clim(app.UIAxes_3, [-30, 0]);
                title(app.UIAxes_3, 'RTM (Log)', 'FontName', Font_Name, 'FontSize', Font_Size_Title, 'FontWeight', Font_Weight_Title);
                xlabel(app.UIAxes_3, 'Time (s)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylabel(app.UIAxes_3, 'Range (m)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylim(app.UIAxes_3, [plot_r_min, plot_r_max]); 
                
                % Target DTM (Log) visualization to app.UIAxes_4
                imagesc(app.UIAxes_4, time_stft, F_doppler, DTM_Mag_Log_Norm);
                set(app.UIAxes_4, 'YDir', 'normal', 'FontName', Font_Name, 'FontSize', Font_Size_Basis, 'FontWeight', Font_Weight_Basis);
                colormap(app.UIAxes_4, JoeyBG_Colormap_Flip); 
                clim(app.UIAxes_4, [-30, 0]);
                title(app.UIAxes_4, 'DTM (Log)', 'FontName', Font_Name, 'FontSize', Font_Size_Title, 'FontWeight', Font_Weight_Title);
                xlabel(app.UIAxes_4, 'Time (s)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylabel(app.UIAxes_4, 'Doppler (Hz)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylim(app.UIAxes_4, [-PRF/2, PRF/2]); 

                %% Visualizing the Motion
                app.agent_think("Constructing the primary three dimensional workspace rendering pipeline to project and animate the physical motions and structural scene layouts concurrently.");
                
                frames = Simulation_Params.num_frames;

                % Assign the 3D target geometry to UIAxes_5 exclusively
                ax_3d = app.UIAxes_5;
                hold(ax_3d, 'on');
                grid(ax_3d, 'on');
                
                set(ax_3d, 'FontName', Font_Name, 'FontSize', Font_Size_Basis, 'FontWeight', Font_Weight_Basis);
                title(ax_3d, 'Motion Visualization', 'FontName', Font_Name, 'FontSize', Font_Size_Title, 'FontWeight', Font_Weight_Title);
                xlabel(ax_3d, 'X (Azimuth, m)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                ylabel(ax_3d, 'Y (Range, m)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                zlabel(ax_3d, 'Z (Height, m)', 'FontName', Font_Name, 'FontSize', Font_Size_Axis, 'FontWeight', Font_Weight_Axis);
                
                view(ax_3d, 45, 20);
                colormap(ax_3d, JoeyBG_Colormap_Flip);
                clim(ax_3d, [0, 1]); 
                
                cb = colorbar(ax_3d);
                ylabel(cb, 'Normalized RCS Value', 'FontName', Font_Name, 'FontSize', Font_Size_Basis, 'FontWeight', Font_Weight_Basis);
                
                X_all_frames = motion_mat(:, :, 1);
                Y_all_frames = motion_mat(:, :, 3); 
                
                pad = 0.5;
                x_min = min(min(X_all_frames, [], 'all'), min([tx_pos(1), rx_pos(1)])) - pad;
                x_max = max(max(X_all_frames, [], 'all'), max([tx_pos(1), rx_pos(1)])) + pad;
                y_min = min(min(Y_all_frames, [], 'all'), min([tx_pos(2), rx_pos(2)])) - pad;
                y_max = max(max(Y_all_frames, [], 'all'), max([tx_pos(2), rx_pos(2)])) + pad;
                
                xlim(ax_3d, [x_min, x_max]);
                ylim(ax_3d, [y_min, y_max]);
                zlim(ax_3d, [0, max(tx_pos(3), 2.2)]);
                
                grid_step = 0.15; 
                x_grid_vals = floor(x_min):grid_step:ceil(x_max);
                y_grid_vals = floor(y_min):grid_step:ceil(y_max);
                [GX, GY] = meshgrid(x_grid_vals, y_grid_vals);
                GZ = zeros(size(GX));
                
                surf(GX, GY, GZ, 'Parent', ax_3d, ...
                     'FaceColor', [0.25 0.25 0.25], 'FaceAlpha', 0.6, ...
                     'EdgeColor', [0.15 0.15 0.15], 'EdgeAlpha', 0.5, ...
                     'HandleVisibility', 'off');
                     
                plot3(ax_3d, tx_pos(1), tx_pos(2), tx_pos(3), '^', 'MarkerSize', 12, 'MarkerFaceColor', JoeyBG_Colormap(2,:), 'MarkerEdgeColor', 'k', 'DisplayName', 'TX Antenna');
                plot3(ax_3d, rx_pos(1), rx_pos(2), rx_pos(3), 'v', 'MarkerSize', 12, 'MarkerFaceColor', JoeyBG_Colormap(end-1,:), 'MarkerEdgeColor', 'k', 'DisplayName', 'RX Antenna');
                
                if Simulation_Params.enable_wall
                    wc = Simulation_Params.wall_center;
                    wd = Simulation_Params.wall_dimensions;
                    
                    X_w = wc(1) + [-1 1 1 -1 -1 1 1 -1] * wd(1)/2;
                    Y_w = wc(2) + [-1 -1 1 1 -1 -1 1 1] * wd(2)/2;
                    Z_w = wc(3) + [-1 -1 -1 -1 1 1 1 1] * wd(3)/2;
                    
                    faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                    patch('Vertices', [X_w' Y_w' Z_w'], 'Faces', faces, 'Parent', ax_3d, ...
                          'FaceColor', [0.7 0.7 0.8], 'FaceAlpha', 0.3, 'EdgeColor', [0.4 0.4 0.5], ...
                          'DisplayName', 'Simulation Wall');
                end
                      
                camlight(ax_3d, 'headlight');
                camlight(ax_3d, 'left');
                lighting(ax_3d, 'gouraud');
                material(ax_3d, 'dull'); 
                
                rep_joint_color = JoeyBG_Colormap(4, :);
                rep_bone_color = JoeyBG_Colormap_Flip(4, :);
                
                plot3(ax_3d, nan, nan, nan, 'o', 'MarkerFaceColor', rep_joint_color, 'MarkerEdgeColor', 'none', 'MarkerSize', 10, 'DisplayName', 'Human Joints');
                plot3(ax_3d, nan, nan, nan, '-', 'Color', rep_bone_color, 'LineWidth', 4, 'DisplayName', 'Human Bones');
                plot3(ax_3d, nan, nan, nan, '-', 'Color', [rep_joint_color, 0.4], 'LineWidth', 1.5, 'DisplayName', 'Joint Trajectories');
                legend(ax_3d, 'Location', 'northeast', 'FontName', Font_Name, 'FontSize', Font_Size_Basis - 2);

                [cyl_x, cyl_y, cyl_z] = cylinder([1.0, 0.65], 20); 
                [sph_x, sph_y, sph_z] = sphere(20);                
                
                num_joints = Simulation_Params.num_joints;
                num_bones = size(kinematic_tree, 1);
                cmap_len = size(JoeyBG_Colormap, 1);
                
                rcs_color_indices = round((1 - Normalized_RCS) * (cmap_len - 1)) + 1;
                rcs_color_indices = max(min(rcs_color_indices, cmap_len), 1); 
                
                h_joints_t = gobjects(num_joints, 1);
                h_bones_t = gobjects(num_bones, 1);
                h_trajs = gobjects(num_joints, 1);
                
                for j = 1:num_joints
                    rcs_idx = rcs_color_indices(j);
                    joint_color = JoeyBG_Colormap(rcs_idx, :);
                    
                    h_joints_t(j) = hgtransform('Parent', ax_3d);
                    surf(sph_x, sph_y, sph_z, 'Parent', h_joints_t(j), 'FaceColor', joint_color, ...
                         'EdgeColor', 'none', 'HandleVisibility', 'off', ...
                         'SpecularStrength', 0.2, 'DiffuseStrength', 0.8);
                         
                    h_trajs(j) = plot3(ax_3d, nan, nan, nan, 'Color', [joint_color, 0.35], ...
                                       'LineWidth', 1.5, 'HandleVisibility', 'off');
                end
                
                for b = 1:num_bones
                    j1 = kinematic_tree(b, 1);
                    j2 = kinematic_tree(b, 2);
                    
                    color1 = JoeyBG_Colormap(rcs_color_indices(j1), :);
                    color2 = JoeyBG_Colormap(rcs_color_indices(j2), :);
                    
                    num_points = size(cyl_z, 2);
                    bone_cdata = zeros(2, num_points, 3);
                    for c_idx = 1:3
                        bone_cdata(1, :, c_idx) = color1(c_idx);
                        bone_cdata(2, :, c_idx) = color2(c_idx);
                    end
                    
                    h_bones_t(b) = hgtransform('Parent', ax_3d);
                    
                    surf(cyl_x, cyl_y, cyl_z, bone_cdata, 'Parent', h_bones_t(b), ...
                         'FaceColor', 'interp', ...
                         'EdgeColor', 'none', 'HandleVisibility', 'off', ...
                         'SpecularStrength', 0.1, 'DiffuseStrength', 0.9);
                end
                
                traj_X = nan(frames, num_joints);
                traj_Y = nan(frames, num_joints);
                traj_Z = nan(frames, num_joints);

                app.agent_think("Synchronizing internal plot rendering loops to animate the target joints along their mathematically resolved spatial vectors in real time.");
                
                for f = 1:frames
                    if ~isvalid(app) || strcmp(app.Switch.Value, 'Off')
                        break; 
                    end
                    
                    curr_joints = squeeze(motion_mat(f, :, :));
                    
                    X_data = curr_joints(:, 1);
                    Y_data = curr_joints(:, 3); 
                    Z_data = curr_joints(:, 2); 
                    
                    traj_X(f, :) = X_data';
                    traj_Y(f, :) = Y_data';
                    traj_Z(f, :) = Z_data';
                    
                    for j = 1:num_joints
                        h_trajs(j).XData = traj_X(1:f, j);
                        h_trajs(j).YData = traj_Y(1:f, j);
                        h_trajs(j).ZData = traj_Z(1:f, j);
                    end
                    
                    for j = 1:num_joints
                        T = makehgtform('translate', [X_data(j), Y_data(j), Z_data(j)]);
                        S = makehgtform('scale', Simulation_Params.joint_radii(j));
                        h_joints_t(j).Matrix = T * S;
                    end
                    
                    for b = 1:num_bones
                        j1 = kinematic_tree(b, 1);
                        j2 = kinematic_tree(b, 2);
                        
                        p1 = [X_data(j1), Y_data(j1), Z_data(j1)];
                        p2 = [X_data(j2), Y_data(j2), Z_data(j2)];
                        
                        h_bones_t(b).Matrix = app.compute_bone_matrix(p1, p2, Simulation_Params.bone_radius);
                    end
                    
                    drawnow limitrate;
                    pause(1 / Simulation_Params.FPS); 
                end
                
                app.agent_think("The entire complex sequence of mathematical simulation formulations and graphical layout processes has been successfully concluded.");

                % Turn switch off and status lamp green successfully
                app.Switch.Value = 'Off';
                app.StatusLamp.Color = [0 1 0]; 
                
                % Export data logic: automatically popup the specific folder 
                if ispc
                    winopen(Save_Dir);
                elseif ismac
                    system(['open "', Save_Dir, '"']);
                else
                    system(['xdg-open "', Save_Dir, '"']);
                end
                
            catch ME
                % Reset UI on failure
                app.StatusLamp.Color = [1 0 0]; % Red
                app.Switch.Value = 'Off';
                
                app.agent_think("A severe procedural violation occurred abruptly halting the algorithmic flow. Rolling back to original directory context and printing the system exception memory trace below for physical debugging.");
                app.agent_think(ME.message);
                
                if exist('original_dir', 'var')
                    cd(original_dir);
                end
            end

        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            % Open a file dialog to select a reference image for the simulation
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, 'Select a Reference Image');
            if ischar(file)
                % If a valid image file is selected, update the EditField with the absolute path
                full_path = fullfile(path, file);
                app.EditField.Value = full_path;
            else
                % If the user cancels the selection, clear the EditField path
                app.EditField.Value = '';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RadHARSimulatorV3RadHARAGTUIFigure = uifigure('Visible', 'off');
            colormap(app.RadHARSimulatorV3RadHARAGTUIFigure, 'jet');
            app.RadHARSimulatorV3RadHARAGTUIFigure.Name = 'RadHARSimulator V3 (RadHARAGT)';
            app.RadHARSimulatorV3RadHARAGTUIFigure.Icon = fullfile(pathToMLAPP, 'RadHARAGT_Logo.png');
            app.UIAxes = uiaxes(app.RadHARSimulatorV3RadHARAGTUIFigure);
            title(app.UIAxes, 'RTM')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Range (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RadHARSimulatorV3RadHARAGTUIFigure);
            title(app.UIAxes_2, 'DTM')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Doppler (Hz)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RadHARSimulatorV3RadHARAGTUIFigure);
            title(app.UIAxes_3, 'RTM (Log)')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Range (m)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.RadHARSimulatorV3RadHARAGTUIFigure);
            title(app.UIAxes_4, 'DTM (Log)')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Doppler (Hz)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.RadHARSimulatorV3RadHARAGTUIFigure);
            title(app.UIAxes_5, 'Motion Visualization')
            xlabel(app.UIAxes_5, 'X (m)')
            ylabel(app.UIAxes_5, 'Y (m)')
            zlabel(app.UIAxes_5, 'Z (m)')
            app.Image = uiimage(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.Image.ImageSource = 'RadHARAGT_Interface.png';
            app.TextArea = uitextarea(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.TextArea.Tooltip = {'Describe the desired human activity and specify radar scene parameters using natural language. The agent model parses this text to configure the physical environment and refine the motion generation prompt.'; 'Supported parameters include: Center Frequency, Pulse Duration, Bandwidth, Pulse Repetition Frequency, Sampling Rate, Transmitter Position, Receiver Position, Antenna Gain, Antenna Isolation, Signal to Noise Ratio, Wall Enablement Status, Multipath Effect Status, Wall Center Coordinates, Wall Physical Dimensions, Wall Relative Permittivity, Wall Loss Tangent, Short Time Fourier Transform Window Duration, Short Time Fourier Transform Overlap Ratio, and Neural Network Denoising Enablement.'};
            app.TextArea.Value = {'我现在需要仿真一个人绕圈步行的行为。采用2GHz中心频率、1GHz带宽的超宽带雷达，PRF设置为400Hz，发射天线位置设置为[0, 5, 1]，接收天线位置设置为[0.25, 5, 1]，天线增益设置为11dBi，系统信噪比设置为50dB。场景中需要启用墙体，墙体位置设置为[0, 4.5, 1]，墙体宽度5米、高度3米、厚度0.2米，墙体介电常数设置为4.5、损耗角正切设置为0.05。仿真过程需要考虑多径效应，但不使用神经网络进行特征增强。'};
            app.WhatcanIsimulateforyoutodayLabel = uilabel(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.WhatcanIsimulateforyoutodayLabel.Tooltip = {'Describe the desired human activity and specify radar scene parameters using natural language. The agent model parses this text to configure the physical environment and refine the motion generation prompt.'; 'Supported parameters include: Center Frequency, Pulse Duration, Bandwidth, Pulse Repetition Frequency, Sampling Rate, Transmitter Position, Receiver Position, Antenna Gain, Antenna Isolation, Signal to Noise Ratio, Wall Enablement Status, Multipath Effect Status, Wall Center Coordinates, Wall Physical Dimensions, Wall Relative Permittivity, Wall Loss Tangent, Short Time Fourier Transform Window Duration, Short Time Fourier Transform Overlap Ratio, and Neural Network Denoising Enablement.'};
            app.WhatcanIsimulateforyoutodayLabel.Text = 'What can I simulate for you today?';
            app.ChooseImageButton = uibutton(app.RadHARSimulatorV3RadHARAGTUIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Tooltip = {'Select a local image file to provide visual reference for the human motion.'; 'If you don''t have any image to upload, leave blank. The agent will only use textual prompt for motion generation and radar simulation.'};
            app.ChooseImageButton.Text = 'Choose Image';
            app.EditField = uieditfield(app.RadHARSimulatorV3RadHARAGTUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Tooltip = {'Select a local image file to provide visual reference for the human motion.'; 'If you don''t have any image to upload, leave blank. The agent will only use textual prompt for motion generation and radar simulation.'};
            app.ImportLLMEditFieldLabel = uilabel(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.ImportLLMEditFieldLabel.Text = 'Import LLM';
            app.ImportLLMEditField = uieditfield(app.RadHARSimulatorV3RadHARAGTUIFigure, 'text');
            app.ImportLLMEditField.Tooltip = {'Specify the exact name of the local Ollama model designated for parameter extraction and text analysis.'; 'Ensure the Ollama software is running.'};
            app.ImportLLMEditField.Value = 'qwen3-vl:4b';
            app.StatusLamp = uilamp(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.StatusLamp.Tooltip = {'Status lamp.'; 'Green: Initialization or finished running.'; 'Yellow: Running.'; 'Red: Error during running.'};
            app.AgentWorkingLabel = uilabel(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.AgentWorkingLabel.Tooltip = {'Monitors the real-time execution status of the simulation pipeline. This panel outputs system messages, shell command execution results, and raw structured data returned by the language model.'};
            app.AgentWorkingLabel.Text = 'Agent Working...';
            app.TemperatureSliderLabel = uilabel(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.TemperatureSliderLabel.Text = 'Temperature';
            app.TemperatureSlider = uislider(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.TemperatureSlider.Limits = [0 10];
            app.TemperatureSlider.Step = 0.1;
            app.TemperatureSlider.Tooltip = {'Adjust the inference randomness of the agent.'; 'Lower value means better prompt guidance but less creative.'; 'Suggested value: 2.5 ~ 7.'};
            app.TemperatureSlider.Value = 5;
            app.Switch = uiswitch(app.RadHARSimulatorV3RadHARAGTUIFigure, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Switch this button to start agentic simulation.'};
            app.AuthorJoeyBGAffiliationBeijingInstituteofTechnologyLabel = uilabel(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.AuthorJoeyBGAffiliationBeijingInstituteofTechnologyLabel.Text = 'Author: JoeyBG, Affiliation: Beijing Institute of Technology';
            app.ResultsPlottingLabel = uilabel(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.ResultsPlottingLabel.Tooltip = {'Monitors the real-time execution status of the simulation pipeline. This panel outputs system messages, shell command execution results, and raw structured data returned by the language model.'};
            app.ResultsPlottingLabel.Text = 'Results Plotting';
            app.TextArea_2 = uitextarea(app.RadHARSimulatorV3RadHARAGTUIFigure);
            app.TextArea_2.Editable = 'off';
            % Show the figure after all components are created
            app.RadHARSimulatorV3RadHARAGTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadHARSimulatorV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RadHARSimulatorV3RadHARAGTUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RadHARSimulatorV3RadHARAGTUIFigure)
        end
    end
end